classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofgeneratedobjectsLabel  matlab.ui.control.Label
        amp_harmk_value1k_value04k_value02k_value01k_value005Label  matlab.ui.control.Label
        HTML_6                         matlab.ui.control.HTML
        ProjectionshadowsimulationLabel  matlab.ui.control.Label
        RandomShadowMaskCheckBox       matlab.ui.control.CheckBox
        ShowPreviewCheckBox            matlab.ui.control.CheckBox
        DesignoftheparametersofthemeasuredobjectLabel  matlab.ui.control.Label
        HTML_5                         matlab.ui.control.HTML
        GratingnoiseparametersettingLabel  matlab.ui.control.Label
        ProjectiongratingparametersettingLabel  matlab.ui.control.Label
        SystemparametersettingLabel    matlab.ui.control.Label
        RandomMatrixSpinner            matlab.ui.control.Spinner
        RandomMatrixSpinnerLabel       matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        SavePathEditFieldLabel         matlab.ui.control.Label
        StartGeneratingButton          matlab.ui.control.Button
        HTML_4                         matlab.ui.control.HTML
        MaxHeightSpinner               matlab.ui.control.Spinner
        MaxHeightSpinnerLabel          matlab.ui.control.Label
        GaussianSurfaceSpinner         matlab.ui.control.Spinner
        GaussianSurfaceSpinnerLabel    matlab.ui.control.Label
        HollowCylinderSpinner          matlab.ui.control.Spinner
        HollowCylinderSpinnerLabel     matlab.ui.control.Label
        FrustumSpinner                 matlab.ui.control.Spinner
        FrustumSpinnerLabel            matlab.ui.control.Label
        HollowSphereSpinner            matlab.ui.control.Spinner
        HollowSphereSpinnerLabel       matlab.ui.control.Label
        RingSpinner                    matlab.ui.control.Spinner
        RingSpinnerLabel               matlab.ui.control.Label
        LadderSpinner                  matlab.ui.control.Spinner
        LadderSpinnerLabel             matlab.ui.control.Label
        BallSpinner_4                  matlab.ui.control.Spinner
        BallSpinner_4Label             matlab.ui.control.Label
        ConeSpinner                    matlab.ui.control.Spinner
        ConeSpinnerLabel               matlab.ui.control.Label
        HTML_3                         matlab.ui.control.HTML
        AmplitudeSpinner               matlab.ui.control.Spinner
        AmplitudeSpinnerLabel          matlab.ui.control.Label
        OrdersSpinner                  matlab.ui.control.Spinner
        OrdersSpinnerLabel             matlab.ui.control.Label
        HarmonicNoiseCheckBox          matlab.ui.control.CheckBox
        MaxSpinner                     matlab.ui.control.Spinner
        MaxSpinnerLabel                matlab.ui.control.Label
        MinSpinner                     matlab.ui.control.Spinner
        MinSpinnerLabel                matlab.ui.control.Label
        RandomNoiseCheckBox            matlab.ui.control.CheckBox
        VarianceSpinner                matlab.ui.control.Spinner
        VarianceSpinnerLabel           matlab.ui.control.Label
        MeanSpinner                    matlab.ui.control.Spinner
        MeanSpinnerLabel               matlab.ui.control.Label
        GaussianNoiseCheckBox          matlab.ui.control.CheckBox
        HTML_2                         matlab.ui.control.HTML
        HTML                           matlab.ui.control.HTML
        MfrequencyandNstepphaseshiftsLabel  matlab.ui.control.Label
        FrequencySpinner               matlab.ui.control.Spinner
        FrequencySpinnerLabel          matlab.ui.control.Label
        ProjectionStepsSpinner         matlab.ui.control.Spinner
        ProjectionStepsSpinnerLabel    matlab.ui.control.Label
        ProjectionPixelPeriodTextArea  matlab.ui.control.TextArea
        ProjectionPixelPeriodTextAreaLabel  matlab.ui.control.Label
        DistancefromcameratoreferenceplanemmLabel  matlab.ui.control.Label
        ThedistancebetweenthecameraandtheprojectorLabel  matlab.ui.control.Label
        LengthSpinner                  matlab.ui.control.Spinner
        LengthSpinnerLabel             matlab.ui.control.Label
        DistanceSpinner                matlab.ui.control.Spinner
        DistanceSpinnerLabel           matlab.ui.control.Label
        ImagesHeightSpinner            matlab.ui.control.Spinner
        ImagesHeightSpinnerLabel       matlab.ui.control.Label
        ImagesWidthSpinner             matlab.ui.control.Spinner
        ImagesWidthSpinnerLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "Structured light 3D system simulation";
            cd(strcat(fileparts(mfilename('fullpath')), "/code/"))
            app.SavePathEditField.Value=strcat(fileparts(mfilename('fullpath')), "\save\");
        end

        % Button pushed function: StartGeneratingButton
        function StartGeneratingButtonPushed(app, event)
            app_save_path = app.SavePathEditField.Value;
            app_width=app.ImagesWidthSpinner.Value;
            app_height=app.ImagesHeightSpinner.Value;
            app_distance=app.DistanceSpinner.Value;
            app_length=app.LengthSpinner.Value;
            app_ps=app.ProjectionStepsSpinner.Value;
            app_freq=app.FrequencySpinner.Value;
            app_gn=1 * app.GaussianNoiseCheckBox.Value;
            app_gn_mean=app.MeanSpinner.Value;
            app_gn_var=app.VarianceSpinner.Value;
            app_rn=1 * app.RandomNoiseCheckBox.Value;
            app_rn_min=app.MinSpinner.Value;
            app_rn_max=app.MaxSpinner.Value;
            app_mn=1 * app.HarmonicNoiseCheckBox.Value;
            app_mn_order=app.OrdersSpinner.Value;
            app_mn_val=app.AmplitudeSpinner.Value;
            app_heitht_max=app.MaxHeightSpinner.Value;
            app_ball=app.BallSpinner_4.Value;
            app_ladder=app.LadderSpinner.Value;
            app_ring=app.RingSpinner.Value;
            app_ring_tube=app.HollowSphereSpinner.Value;
            app_cone=app.ConeSpinner.Value;
            app_cone_table=app.FrustumSpinner.Value;
            app_sc=app.HollowCylinderSpinner.Value;
            app_gs=app.GaussianSurfaceSpinner.Value;
            app_rm=app.RandomMatrixSpinner.Value;
            app_prev=app.ShowPreviewCheckBox.Value;
            app_mask=1 * app.RandomShadowMaskCheckBox.Value;
            app_pc_list=zeros(1,length(app.ProjectionPixelPeriodTextArea.Value));
            for i = 1:length(app.ProjectionPixelPeriodTextArea.Value)
                val = app.ProjectionPixelPeriodTextArea.Value{i};
                val = strrep(val, 'width', num2str(app_width));
                val = strrep(val, 'height',  num2str(app_height));
                app_pc_list(i) = evalin('base', val);
            end
            close all;
            controls = findobj(app.UIFigure);
            for i = 1:length(controls)
                if isprop(controls(i), 'Enable')
                    set(controls(i), 'Enable', 'off');
                end
            end
            if ~app_prev
                msgbox("Generating...");
            end
            elapsed_time = dataset_gene(app_save_path, app_width, app_height, app_distance, app_length, app_pc_list, app_ps, app_freq, app_gn, app_gn_mean, app_gn_var, app_rn, app_rn_min, app_rn_max, app_mn, app_mn_order, app_mn_val, app_heitht_max, app_ball, app_ladder, app_ring, app_ring_tube, app_cone, app_cone_table, app_sc, app_gs, app_rm, app_prev, app_mask);
            if ~app_prev
                close all;
            end
            msgbox(strcat("Generated Finished in ", num2str(elapsed_time/60), " minutes"));
            for i = 1:length(controls)
                if isprop(controls(i), 'Enable')
                    set(controls(i), 'Enable', 'on');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagesWidthSpinnerLabel = uilabel(app.UIFigure);
            app.ImagesWidthSpinnerLabel.Text = 'Images Width';
            app.ImagesWidthSpinner = uispinner(app.UIFigure);
            app.ImagesWidthSpinner.Limits = [10 10000];
            app.ImagesWidthSpinner.Value = 576;
            app.ImagesHeightSpinnerLabel = uilabel(app.UIFigure);
            app.ImagesHeightSpinnerLabel.Text = 'Images Height';
            app.ImagesHeightSpinner = uispinner(app.UIFigure);
            app.ImagesHeightSpinner.Limits = [10 10000];
            app.ImagesHeightSpinner.Value = 448;
            app.DistanceSpinnerLabel = uilabel(app.UIFigure);
            app.DistanceSpinnerLabel.Text = 'Distance';
            app.DistanceSpinner = uispinner(app.UIFigure);
            app.DistanceSpinner.Limits = [0 Inf];
            app.DistanceSpinner.Value = 50;
            app.LengthSpinnerLabel = uilabel(app.UIFigure);
            app.LengthSpinnerLabel.Text = 'Length';
            app.LengthSpinner = uispinner(app.UIFigure);
            app.LengthSpinner.Limits = [0 Inf];
            app.LengthSpinner.Value = 200;
            app.ThedistancebetweenthecameraandtheprojectorLabel = uilabel(app.UIFigure);
            app.ThedistancebetweenthecameraandtheprojectorLabel.Text = 'The distance between the camera and the projector';
            app.DistancefromcameratoreferenceplanemmLabel = uilabel(app.UIFigure);
            app.DistancefromcameratoreferenceplanemmLabel.Text = 'Distance from camera to reference plane (mm)';
            app.ProjectionPixelPeriodTextAreaLabel = uilabel(app.UIFigure);
            app.ProjectionPixelPeriodTextAreaLabel.Text = {'Projection'; 'Pixel Period'};
            app.ProjectionPixelPeriodTextArea = uitextarea(app.UIFigure);
            app.ProjectionPixelPeriodTextArea.Value = {'width'; 'width/4'; 'width/32'; 'width/64'};
            app.ProjectionStepsSpinnerLabel = uilabel(app.UIFigure);
            app.ProjectionStepsSpinnerLabel.Text = 'Projection Steps';
            app.ProjectionStepsSpinner = uispinner(app.UIFigure);
            app.ProjectionStepsSpinner.Limits = [0 Inf];
            app.ProjectionStepsSpinner.Value = 6;
            app.FrequencySpinnerLabel = uilabel(app.UIFigure);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.UIFigure);
            app.FrequencySpinner.Limits = [0 Inf];
            app.FrequencySpinner.Value = 3;
            app.MfrequencyandNstepphaseshiftsLabel = uilabel(app.UIFigure);
            app.MfrequencyandNstepphaseshiftsLabel.Text = 'M-frequency and N-step phase shifts';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr>';
            app.HTML_2 = uihtml(app.UIFigure);
            app.HTML_2.HTMLSource = '<hr>';
            app.GaussianNoiseCheckBox = uicheckbox(app.UIFigure);
            app.GaussianNoiseCheckBox.Text = 'Gaussian Noise';
            app.GaussianNoiseCheckBox.Value = true;
            app.MeanSpinnerLabel = uilabel(app.UIFigure);
            app.MeanSpinnerLabel.Text = 'Mean';
            app.MeanSpinner = uispinner(app.UIFigure);
            app.VarianceSpinnerLabel = uilabel(app.UIFigure);
            app.VarianceSpinnerLabel.Text = 'Variance';
            app.VarianceSpinner = uispinner(app.UIFigure);
            app.VarianceSpinner.Limits = [0 Inf];
            app.VarianceSpinner.Value = 0.001;
            app.RandomNoiseCheckBox = uicheckbox(app.UIFigure);
            app.RandomNoiseCheckBox.Text = 'Random Noise';
            app.RandomNoiseCheckBox.Value = true;
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.Value = -0.05;
            app.MaxSpinnerLabel = uilabel(app.UIFigure);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.UIFigure);
            app.MaxSpinner.Value = 0.05;
            app.HarmonicNoiseCheckBox = uicheckbox(app.UIFigure);
            app.HarmonicNoiseCheckBox.Text = 'Harmonic Noise';
            app.HarmonicNoiseCheckBox.Value = true;
            app.OrdersSpinnerLabel = uilabel(app.UIFigure);
            app.OrdersSpinnerLabel.Text = 'Orders';
            app.OrdersSpinner = uispinner(app.UIFigure);
            app.OrdersSpinner.Limits = [0 Inf];
            app.OrdersSpinner.Value = 4;
            app.AmplitudeSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.UIFigure);
            app.AmplitudeSpinner.Value = 0.01;
            app.HTML_3 = uihtml(app.UIFigure);
            app.HTML_3.HTMLSource = '<hr>';
            app.ConeSpinnerLabel = uilabel(app.UIFigure);
            app.ConeSpinnerLabel.Text = 'Cone';
            app.ConeSpinner = uispinner(app.UIFigure);
            app.ConeSpinner.Limits = [0 Inf];
            app.ConeSpinner.Value = 1;
            app.BallSpinner_4Label = uilabel(app.UIFigure);
            app.BallSpinner_4Label.Text = 'Ball';
            app.BallSpinner_4 = uispinner(app.UIFigure);
            app.BallSpinner_4.Limits = [0 Inf];
            app.BallSpinner_4.Value = 1;
            app.LadderSpinnerLabel = uilabel(app.UIFigure);
            app.LadderSpinnerLabel.Text = 'Ladder';
            app.LadderSpinner = uispinner(app.UIFigure);
            app.LadderSpinner.Limits = [0 Inf];
            app.LadderSpinner.Value = 1;
            app.RingSpinnerLabel = uilabel(app.UIFigure);
            app.RingSpinnerLabel.Text = 'Ring';
            app.RingSpinner = uispinner(app.UIFigure);
            app.RingSpinner.Limits = [0 Inf];
            app.RingSpinner.Value = 1;
            app.HollowSphereSpinnerLabel = uilabel(app.UIFigure);
            app.HollowSphereSpinnerLabel.Text = 'Hollow Sphere';
            app.HollowSphereSpinner = uispinner(app.UIFigure);
            app.HollowSphereSpinner.Limits = [0 Inf];
            app.HollowSphereSpinner.Value = 1;
            app.FrustumSpinnerLabel = uilabel(app.UIFigure);
            app.FrustumSpinnerLabel.Text = 'Frustum';
            app.FrustumSpinner = uispinner(app.UIFigure);
            app.FrustumSpinner.Limits = [0 Inf];
            app.FrustumSpinner.Value = 1;
            app.HollowCylinderSpinnerLabel = uilabel(app.UIFigure);
            app.HollowCylinderSpinnerLabel.Text = 'Hollow Cylinder';
            app.HollowCylinderSpinner = uispinner(app.UIFigure);
            app.HollowCylinderSpinner.Limits = [0 Inf];
            app.HollowCylinderSpinner.Value = 1;
            app.GaussianSurfaceSpinnerLabel = uilabel(app.UIFigure);
            app.GaussianSurfaceSpinnerLabel.Text = 'Gaussian Surface';
            app.GaussianSurfaceSpinner = uispinner(app.UIFigure);
            app.GaussianSurfaceSpinner.Limits = [0 Inf];
            app.GaussianSurfaceSpinner.Value = 1;
            app.MaxHeightSpinnerLabel = uilabel(app.UIFigure);
            app.MaxHeightSpinnerLabel.Text = 'Max Height';
            app.MaxHeightSpinner = uispinner(app.UIFigure);
            app.MaxHeightSpinner.Limits = [0 Inf];
            app.MaxHeightSpinner.Value = 80;
            app.HTML_4 = uihtml(app.UIFigure);
            app.HTML_4.HTMLSource = '<hr>';
            app.StartGeneratingButton = uibutton(app.UIFigure, 'push');
            app.StartGeneratingButton.ButtonPushedFcn = createCallbackFcn(app, @StartGeneratingButtonPushed, true);
            app.StartGeneratingButton.Text = 'Start Generating';
            app.SavePathEditFieldLabel = uilabel(app.UIFigure);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.SavePathEditField.Value = './save';
            app.RandomMatrixSpinnerLabel = uilabel(app.UIFigure);
            app.RandomMatrixSpinnerLabel.Text = 'Random Matrix';
            app.RandomMatrixSpinner = uispinner(app.UIFigure);
            app.RandomMatrixSpinner.Limits = [0 Inf];
            app.RandomMatrixSpinner.Value = 1;
            app.SystemparametersettingLabel = uilabel(app.UIFigure);
            app.SystemparametersettingLabel.Text = 'System parameter setting';
            app.ProjectiongratingparametersettingLabel = uilabel(app.UIFigure);
            app.ProjectiongratingparametersettingLabel.Text = 'Projection grating parameter setting';
            app.GratingnoiseparametersettingLabel = uilabel(app.UIFigure);
            app.GratingnoiseparametersettingLabel.Text = 'Grating noise parameter setting';
            app.HTML_5 = uihtml(app.UIFigure);
            app.HTML_5.HTMLSource = '<hr>';
            app.DesignoftheparametersofthemeasuredobjectLabel = uilabel(app.UIFigure);
            app.DesignoftheparametersofthemeasuredobjectLabel.Text = 'Design of the parameters of the measured object';
            app.ShowPreviewCheckBox = uicheckbox(app.UIFigure);
            app.ShowPreviewCheckBox.Text = 'Show Preview';
            app.ShowPreviewCheckBox.Value = true;
            app.RandomShadowMaskCheckBox = uicheckbox(app.UIFigure);
            app.RandomShadowMaskCheckBox.Text = 'Random Shadow Mask';
            app.RandomShadowMaskCheckBox.Value = true;
            app.ProjectionshadowsimulationLabel = uilabel(app.UIFigure);
            app.ProjectionshadowsimulationLabel.Text = 'Projection shadow simulation';
            app.HTML_6 = uihtml(app.UIFigure);
            app.HTML_6.HTMLSource = '<hr>';
            app.amp_harmk_value1k_value04k_value02k_value01k_value005Label = uilabel(app.UIFigure);
            app.amp_harmk_value1k_value04k_value02k_value01k_value005Label.Text = 'amp_harm = [k_value*1, k_value*0.4, k_value*0.2, k_value*0.1,k_value*0.05];';
            app.NumberofgeneratedobjectsLabel = uilabel(app.UIFigure);
            app.NumberofgeneratedobjectsLabel.Text = 'Number of generated objects';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end